classdef OfficialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimulinkGUI                 matlab.ui.Figure
        StopButton                  matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        UpdateButton                matlab.ui.control.Button
        KqmophongPanel              matlab.ui.container.Panel
        DMBGTTeEditField            matlab.ui.control.NumericEditField
        mbmgathctEditFieldLabel     matlab.ui.control.Label
        DMBGmmEditField             matlab.ui.control.NumericEditField
        mbmgaLabel                  matlab.ui.control.Label
        vACCEditField               matlab.ui.control.NumericEditField
        TcxeACCmsEditFieldLabel     matlab.ui.control.Label
        VleadEditField              matlab.ui.control.NumericEditField
        TcxephatrcmsEditFieldLabel  matlab.ui.control.Label
        TPS2EditField               matlab.ui.control.NumericEditField
        TPS2EditFieldLabel          matlab.ui.control.Label
        TPS1EditField               matlab.ui.control.NumericEditField
        TPS1EditFieldLabel          matlab.ui.control.Label
        Dothi                       matlab.ui.container.TabGroup
        TcxethctTab                 matlab.ui.container.Tab
        Tocdoxe                     matlab.ui.control.UIAxes
        mbmgamongmunTab             matlab.ui.container.Tab
        Domobuomgamongmuon          matlab.ui.control.UIAxes
        mbmgathctTab                matlab.ui.container.Tab
        DMBGTTe                     matlab.ui.control.UIAxes
        TPSTab                      matlab.ui.container.Tab
        TPS                         matlab.ui.control.UIAxes
        ThoigianEditField           matlab.ui.control.NumericEditField
        ThigianmphngsLabel          matlab.ui.control.Label
        ChondothiDropDown           matlab.ui.control.DropDown
        thDropDownLabel             matlab.ui.control.Label
        Input                       matlab.ui.container.TabGroup
        HsbmgaTab                   matlab.ui.container.Tab
        HesobolocEditField          matlab.ui.control.NumericEditField
        HsblcEditFieldLabel         matlab.ui.control.Label
        HsKiEditField               matlab.ui.control.NumericEditField
        HsKiEditFieldLabel          matlab.ui.control.Label
        HsKpEditField               matlab.ui.control.NumericEditField
        HsKpEditFieldLabel          matlab.ui.control.Label
        ThongsoxeTab                matlab.ui.container.Tab
        ChondongcoButton            matlab.ui.control.Button
        TocdodongcomaxEditField     matlab.ui.control.NumericEditField
        TcngcmaxrpmEditFieldLabel   matlab.ui.control.Label
        CongsuatmaxEditField        matlab.ui.control.NumericEditField
        CngsutmaxLabel              matlab.ui.control.Label
        KhoiluongxeEditField        matlab.ui.control.NumericEditField
        KhilngxekgEditFieldLabel    matlab.ui.control.Label
        ThsodauvaoTab               matlab.ui.container.Tab
        KhoangcachantoanEditField   matlab.ui.control.NumericEditField
        KhongcchtithiumLabel        matlab.ui.control.Label
        VTTDEditField               matlab.ui.control.NumericEditField
        VtrtngimEditFieldLabel      matlab.ui.control.Label
        VSetEditField               matlab.ui.control.NumericEditField
        TcgiihnmsEditFieldLabel     matlab.ui.control.Label
        TychniukhinTab              matlab.ui.container.Tab
        TaoSignalButton             matlab.ui.control.Button
        CknDropDown                 matlab.ui.control.DropDown
        ChncngktniDropDownLabel     matlab.ui.control.Label
        TinhieudieukhienDropDown    matlab.ui.control.DropDown
        TnhiuiukhinDropDownLabel    matlab.ui.control.Label
        ModeDropDown                matlab.ui.control.DropDown
        ModeDropDownLabel           matlab.ui.control.Label
        GIAODINIUKHINBMGALabel      matlab.ui.control.Label
        TRNGIHCBCHKHOAHQGTPHCMKhoaKThutGiaoThngLabel  matlab.ui.control.Label
        Image3                      matlab.ui.control.Image
        Image2                      matlab.ui.control.Image
        Image                       matlab.ui.control.Image
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        timerObj
        TimeLog = [];     % Lưu thời gian
        VAccLog = [];     % Lưu v_ACC
        VLeadLog = [];    % Lưu v_lead
        DMBGLog = [];     % Lưu độ mở bướm ga
        TPS1Log = [];        % Lưu giá trị TPS1
        TPS2Log = [];        % Lưu giá trị TPS2
        ErrorLog = [];
        ActualLog = [];
        TPSmin double;
        TPSmax double; 
        CongCOM
        TPSminList = [];
        TPSmaxList = [];
        isReceiving;
    end
    
    properties (Access = public)
        serialObj % Description
    end

%------------------------------------------------------------------------------------------------------------------------------------------------- 

    methods (Access = private)
        % Hàm cập nhật Edit Fields từ Simulink
        function updateEditFields(app)
            try
            if evalin('base', 'exist(''sendData'', ''var'')')
                    data = evalin('base', 'sendData');

                    app.vACCEditField.Value = data.v_ACC;
                    if data.v_ACC < 0
                        app.vACCEditField.Value = 0;
                    end  
                    app.VleadEditField.Value = data.v_lead;
                    app.DMBGmmEditField.Value = data.Thr;
                    app.ThoigianEditField.Value = data.time;
                    

                currentTime = data.time;  % thời gian lấy từ Simulink
                app.TimeLog(end+1) = currentTime;
                app.VAccLog(end+1) = data.v_ACC;
                app.VLeadLog(end+1) = data.v_lead;
                app.DMBGLog(end+1) = data.Thr;
                % Vẽ dữ liệu
                plot(app.Tocdoxe, app.TimeLog, app.VAccLog, 'b', ...
                                     app.TimeLog, app.VLeadLog, 'r');
                legend(app.Tocdoxe, {'Tốc độ xe bám', 'Tốc độ xe dẫn'}, 'Location', 'best');
                plot(app.Domobuomgamongmuon,app.TimeLog, app.DMBGLog, 'b');
            end
            catch
            end
       
                 
        if evalin('base', 'exist(''sendTPS'', ''var'')')
                    data2 = evalin('base', 'sendTPS');
            app.TPS1EditField.Value = data2.tps1;
            app.TPS2EditField.Value = data2.tps2;
         
            
            app.TPS1Log(end+1) = data2.tps1;
            app.TPS2Log(end+1) = data2.tps2; 
            

            try
            plot(app.TPS, app.TimeLog, app.TPS1Log, 'b',...
                                       app.TimeLog, app.TPS2Log, 'r');
            legend(app.TPS, {'TPS1', 'TPS2'}, 'Location', 'best');

            catch er1
                disp("Lỗi vẽ đồ thị" + er1.message)
            end 
         

 % Tính actual trên toàn bộ vector
TPSmax1 = app.TPSmax;
TPSmin1 = app.TPSmin;

actual = ((app.TPS1Log - app.TPS2Log) - TPSmin1) * 100 / (TPSmax1 - TPSmin1);

% Clamp từng phần tử actual trong khoảng [0, 100]
actual(actual < 0) = 0;
actual(actual > 100) = 100;

% Lấy giá trị actual mới nhất (phần tử cuối cùng) để hiển thị trên EditField
latestActual = actual(end);

app.DMBGTTeEditField.Value = latestActual;

% Lưu lại actual vào log để vẽ đồ thị
app.ActualLog(end+1) = latestActual;

% Vẽ đồ thị actual
plot(app.DMBGTTe, app.TimeLog, app.ActualLog);
        end 
        end 
%------------------------------------------------------------------------------------------------------------------------------------------------- 
    function cleanup(app)
        % Hàm này dừng và xóa timer khi app đóng
        if ~isempty(app.timerObj) && isvalid(app.timerObj)
            stop(app.timerObj);
            delete(app.timerObj);
            app.timerObj = [];
        end

    end
    end   

%------------------------------------------------------------------------------------------------------------------------------------------------- 
    
    methods (Access = public)

        function sendPIDToSerial(app)
    try
        % Mở cổng COM
        app.serialObj = serialport(app.CongCOM, 115200); % Thay COM3 bằng đúng cổng bạn dùng
        pause(0.2);  % Cho cổng ổn định

        % Đóng gói và gửi Kp (ID = 1)
        Kp = app.HsKpEditField.Value;
        assignin('base', "Kp", Kp);
        id1 = uint8(1);
        Kp1 = single(Kp);
        dataKp = [id1, typecast(Kp1, 'uint8')];
        write(app.serialObj, dataKp, "uint8");

        pause(0.1);  % delay nhẹ

        % Đóng gói và gửi Ki (ID = 2)
        Ki = app.HsKiEditField.Value;
        assignin('base', "Ki", Ki);
        id2 = uint8(2);
        Ki1 = single(Ki);
        dataKi = [id2, typecast(Ki1, 'uint8')];
        write(app.serialObj, dataKi, "uint8");

        % Đóng gói và gửi hệ số bộ lọc (ID = 4)
        alpha = app.HesobolocEditField.Value;
        id4 = uint8(4);
        alpha1 = single(alpha);
        dataalpha = [id4, typecast(alpha1, 'uint8')];
        write(app.serialObj, dataalpha, "uint8");

    catch err
        disp("Lỗi gửi dữ liệu: " + err.message);
    end
end

% %------------------------------------------------------------------------------------------------------------------------------------------------- 
    function SendEndingToSerial(app)
        try
        % Mở cổng COM
        app.serialObj = serialport(app.CongCOM, 115200); % Thay COM3 bằng đúng cổng bạn dùng
        pause(0.2);  % Cho cổng ổn định

        % Gửi ending (ID = 3)
            id3 = uint8(3);
            ending = single(0);
            dataending = [id3, typecast(ending, 'uint8')];
            write(app.serialObj, dataending, "uint8");
            pause(0.2); % Cho gửi xong hoàn toàn
            clear app.serialObj; 
            app.serialObj = [];
            disp("Đã gửi xong tín hiệu kết thúc và đóng COM.");
        catch err
            disp("Lỗi gửi dữ liệu: " + err.message);
        end
    end

%------------------------------------------------------------------------------------------------------------------------------------------------- 
function receiveTPS12_0(app, src, ~)
     try
        if ~app.isReceiving
            configureCallback(src, "off");
            return;
        end

        if src.NumBytesAvailable >= 8
            rawData = read(src, 8, "uint8");
            tpsmin_bytes = rawData(1:4);
            tpsmax_bytes = rawData(5:8);

            % Chuyển sang float và double
            TPSmin_0 = double(typecast(uint8(tpsmin_bytes), 'single'));
            TPSmax_0 = double(typecast(uint8(tpsmax_bytes), 'single'));

            % Hiển thị lên giao diện
            % app.TPS1EditField.Value = TPSmin_0;
            % app.TPS2EditField.Value = TPSmax_0;

            % Cập nhật vào biến app
            app.TPSmin = TPSmin_0;
            app.TPSmax = TPSmax_0;

            % Kiểm tra điều kiện dừng
            if TPSmin_0 < 0 || TPSmax_0 < 0
                app.isReceiving = false;
                configureCallback(src, "off");

                % In ra giá trị cuối cùng
                disp('DỪNG NHẬN DỮ LIỆU!');
                disp(['TPSmin: ', num2str(app.TPSmin)]);
                disp(['TPSmax: ', num2str(app.TPSmax)]);
            end
        end
    catch err3
        disp(['Lỗi: ', err3.message])
    end
     end

%------------------------------------------------------------------------------------------------------------------------------------------------- 
function case_Huyndai_PID(~)
    model = 'ACCSimulationByUsingPID';
    oldSignalBuilderBlockName = 'Testcase 1';
    srcBlock_testcase = [model '/Testcase riêng 1'];
    dstBlock_testcase = [model '/Lead vehicle'];

    % Xóa line cũ nối từ khối cũ (Testcase 1)
    lines = find_system(model, 'FindAll', 'on', 'Type', 'line');
    for i = 1:length(lines)
        src = get_param(lines(i), 'SrcBlockHandle');
        if src ~= -1
            srcName = get_param(src, 'Name');
            if strcmp(srcName, oldSignalBuilderBlockName)
                delete_line(lines(i));
            end
        end
    end

    % Lấy PortHandles
    try
        srcPorts = get_param(srcBlock_testcase, 'PortHandles');
        dstPorts = get_param(dstBlock_testcase, 'PortHandles');

        % Đảm bảo tồn tại ít nhất 1 outport và 2 inport
        if isempty(srcPorts.Outport)
            error('Không tìm thấy Outport của %s', srcBlock_testcase);
        end
        if numel(dstPorts.Inport) < 2
            error('Không đủ Inport trên %s', dstBlock_testcase);
        end

        % Thêm line mới
        add_line(model, srcPorts.Outport(1), dstPorts.Inport(2), 'autorouting', 'on');
        disp('Line added thành công!');
    catch ME
        disp(['Không thêm được line: ', ME.message]);
    end

    % Cập nhật mô hình
    set_param(model, 'SimulationCommand', 'update');
    pause(0.5);
end

%------------------------------------------------------------------------------------------------------------------------------------------------- 
     function other_case_PID(~)
    model = 'ACCSimulationByUsingPID';
    oldSignalBuilderBlockName = 'Testcase riêng 1';
    srcBlock_testcase = [model '/Testcase 1'];
    dstBlock_testcase = [model '/Lead vehicle'];

    % Xóa line cũ nối từ khối cũ (Testcase 1)
    lines = find_system(model, 'FindAll', 'on', 'Type', 'line');
    for i = 1:length(lines)
        src = get_param(lines(i), 'SrcBlockHandle');
        if src ~= -1
            srcName = get_param(src, 'Name');
            if strcmp(srcName, oldSignalBuilderBlockName)
                delete_line(lines(i));
            end
        end
    end

    % Lấy PortHandles
    try
        srcPorts = get_param(srcBlock_testcase, 'PortHandles');
        dstPorts = get_param(dstBlock_testcase, 'PortHandles');

        % Đảm bảo tồn tại ít nhất 1 outport và 2 inport
        if isempty(srcPorts.Outport)
            error('Không tìm thấy Outport của %s', srcBlock_testcase);
        end
        if numel(dstPorts.Inport) < 2
            error('Không đủ Inport trên %s', dstBlock_testcase);
        end

        % Thêm line mới
        add_line(model, srcPorts.Outport(1), dstPorts.Inport(2), 'autorouting', 'on');
        disp('Line added thành công!');
    catch ME
        disp(['Không thêm được line: ', ME.message]);
    end

    % Cập nhật mô hình
    set_param(model, 'SimulationCommand', 'update');
    pause(0.5);
     end
     
  %-------------------------------------------------------------------------------------------------------------------------------------------------
  function case_Hyundai_Fuzzy(~)
    model = 'FLC_ACC';
    oldSignalBuilderBlockName = 'test 3';
    srcBlock_testcase = [model '/Test all testcases'];
    dstBlock_testcase = [model '/Lead Car'];

    % Xóa line cũ nối từ khối cũ (Testcase 1)
   lines = find_system(model, 'FindAll', 'on', 'Type', 'line');
for i = 1:length(lines)
    if ishandle(lines(i))
        src = get_param(lines(i), 'SrcBlockHandle');
        if src ~= -1
            srcName = get_param(src, 'Name');
            if strcmp(srcName, oldSignalBuilderBlockName)
                delete_line(lines(i));
            end
        end
    end
end


    % Lấy PortHandles
    try
        srcPorts = get_param(srcBlock_testcase, 'PortHandles');
        dstPorts = get_param(dstBlock_testcase, 'PortHandles');

        % Đảm bảo tồn tại ít nhất 1 outport và 2 inport
        if isempty(srcPorts.Outport)
            error('Không tìm thấy Outport của %s', srcBlock_testcase);
        end
        if numel(dstPorts.Inport) < 2
            error('Không đủ Inport trên %s', dstBlock_testcase);
        end

        % Thêm line mới
        add_line(model, srcPorts.Outport(1), dstPorts.Inport(2), 'autorouting', 'on');
        disp('Line added thành công!');
    catch ME
        disp(['Không thêm được line: ', ME.message]);
    end

    % Cập nhật mô hình
    set_param(model, 'SimulationCommand', 'update');
    pause(0.5);
     end
   
  %-------------------------------------------------------------------------------------------------------------------------------------------------
  function other_case_Fuzzy(~)
    model = 'FLC_ACC';
    oldSignalBuilderBlockName = 'Test all testcases';
    srcBlock_testcase = [model '/test 3'];
    dstBlock_testcase = [model '/Lead Car'];

    % Xóa line cũ nối từ khối cũ (Testcase 1)
    lines = find_system(model, 'FindAll', 'on', 'Type', 'line');
    for i = 1:length(lines)
    if ishandle(lines(i))
        src = get_param(lines(i), 'SrcBlockHandle');
        if src ~= -1
            srcName = get_param(src, 'Name');
            if strcmp(srcName, oldSignalBuilderBlockName)
                delete_line(lines(i));
            end
        end
    end
    end


    % Lấy PortHandles
    try
        srcPorts = get_param(srcBlock_testcase, 'PortHandles');
        dstPorts = get_param(dstBlock_testcase, 'PortHandles');

        % Đảm bảo tồn tại ít nhất 1 outport và 2 inport
        if isempty(srcPorts.Outport)
            error('Không tìm thấy Outport của %s', srcBlock_testcase);
        end
        if numel(dstPorts.Inport) < 2
            error('Không đủ Inport trên %s', dstBlock_testcase);
        end

        % Thêm line mới
        add_line(model, srcPorts.Outport(1), dstPorts.Inport(2), 'autorouting', 'on');
        disp('Line added thành công!');
    catch ME
        disp(['Không thêm được line: ', ME.message]);
    end

    % Cập nhật mô hình
    set_param(model, 'SimulationCommand', 'update');
    pause(0.5);
     end

  %-------------------------------------------------------------------------------------------------------------------------------------------------

  %-------------------------------------------------------------------------------------------------------------------------------------------------

  end 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    % v_acc
    v_acc_sig = Simulink.Signal;
    
    % Các thuộc tính tương đương với ảnh bạn gửi
    v_acc_sig.DataType = 'double';         % auto tương đương double nếu không rõ
    v_acc_sig.Dimensions = 1;              % hoặc [1 1] nếu cần ma trận
    v_acc_sig.InitialValue = '0';          % Khởi tạo 0
    v_acc_sig.Complexity = 'real';         % auto --> real
    v_acc_sig.Min = [];                    % [ ] nghĩa là không giới hạn
    v_acc_sig.Max = [];
    v_acc_sig.Unit = '';                   % không đơn vị
    v_acc_sig.SampleTime = -1;             % -1 là sample time kế thừa

    % Gửi vào Base Workspace
    assignin('base', 'v_ACC', v_acc_sig);

    % v_lead
    v_lead_sig = Simulink.Signal;
    
    % Các thuộc tính tương đương với ảnh bạn gửi
    v_lead_sig.DataType = 'double';         % auto tương đương double nếu không rõ
    v_lead_sig.Dimensions = 1;              % hoặc [1 1] nếu cần ma trận
    v_lead_sig.InitialValue = '0';          % Khởi tạo 0
    v_lead_sig.Complexity = 'real';         % auto --> real
    v_lead_sig.Min = [];                    % [ ] nghĩa là không giới hạn
    v_lead_sig.Max = [];
    v_lead_sig.Unit = '';                   % không đơn vị
    v_lead_sig.SampleTime = -1;             % -1 là sample time kế thừa

    % Gửi vào Base Workspace
    assignin('base', 'v_lead', v_lead_sig);

    % Thr
    Thr_sig = Simulink.Signal;
    
    % Các thuộc tính tương đương với ảnh bạn gửi
    Thr_sig.DataType = 'double';         % auto tương đương double nếu không rõ
    Thr_sig.Dimensions = 1;              % hoặc [1 1] nếu cần ma trận
    Thr_sig.InitialValue = '0';          % Khởi tạo 0
    Thr_sig.Complexity = 'real';         % auto --> real
    Thr_sig.Min = [];                    % [ ] nghĩa là không giới hạn
    Thr_sig.Max = [];
    Thr_sig.Unit = '';                   % không đơn vị
    Thr_sig.SampleTime = -1;             % -1 là sample time kế thừa

    % Gửi vào Base Workspace
    assignin('base', 'Thr', Thr_sig);
        end

        % Value changed function: KhoiluongxeEditField
        function KhoiluongxeEditFieldValueChanged(app, event)
            % app.KhoiluongxeEditField.Value = 1200; 
            % mass = app.KhoiluongxeEditField.Value;
            % set_param('ACCSimulationByUsingPID/ACC Vehicle/Subsystem2/Vehicle Body', 'mass', num2str(mass));
        end

        % Value changed function: CongsuatmaxEditField
        function CongsuatmaxEditFieldValueChanged(app, event)
            % app.CongsuatmaxEditField.Value = 105;
            % P_max = app.CongsuatmaxEditField.Value;
            % set_param('ACCSimulationByUsingPID/ACC Vehicle/Subsystem/Engine', 'P_max', num2str(P_max));
        end

        % Value changed function: TocdodongcomaxEditField
        function TocdodongcomaxEditFieldValueChanged(app, event)
            % app.TocdodongcomaxEditField.Value = 8500;
            % w_max = app.TocdodongcomaxEditField.Value;
            % set_param('ACCSimulationByUsingPID/ACC Vehicle/Subsystem/Engine', 'w_max', num2str(w_max));
        end

        % Button pushed function: ChondongcoButton
        function ChondongcoButtonPushed(app, event)
         try
        switch app.ModeDropDown.Value
            case 'PID Controller'
                modelPath = 'ACCSimulationByUsingPID/ACC Vehicle/Subsystem/Engine';
            case 'Fuzzy Logic Controller'
                modelPath = 'FLC_ACC/ACC Car/ACC Car/Car/Engine';
        end
        evalin('base', sprintf("open_system('%s')", modelPath));
        pause(0.5); % đợi mô hình load
        catch ME
        uialert(app.SimulinkGUI, ME.message, 'Lỗi khi mở Signal Builder');
         end 
        end

        % Value changed function: VTTDEditField
        function VTTDEditFieldValueChanged(app, event)
            % app.VTTDEditField.Value = 5;
            % Vị trí tương đối
            d_initial = app.VTTDEditField.Value;
            assignin('base', 'd_initial', d_initial);
        end

        % Value changed function: KhoangcachantoanEditField
        function KhoangcachantoanEditFieldValueChanged(app, event)
            % app.KhoangcachantoanEditField.Value = 14.3;
             % Khoảng cách tối thiểu
            d_min = app.KhoangcachantoanEditField.Value;
            assignin('base', 'd_min', d_min);
        end

        % Value changed function: ChondothiDropDown
        function ChondothiDropDownValueChanged(app, event)
            dt = app.ChondothiDropDown.Value;
    switch dt
        case 'Tốc độ xe thực tế'
            app.Dothi.SelectedTab = app.TcxethctTab;
        case 'Độ mở bướm ga thực tế'
            app.Dothi.SelectedTab = app.mbmgathctTab; 
        case 'TPS'
            app.Dothi.SelectedTab = app.TPSTab;
        case 'Độ mở bướm ga mong muốn'
            app.Dothi.SelectedTab = app.mbmgamongmunTab;
    end


        end

        % Button pushed function: TaoSignalButton
        function TaoSignalButtonPushed(app, event)
    try
        switch app.ModeDropDown.Value
            case 'PID Controller'
                modelPath = 'ACCSimulationByUsingPID/Testcase 1';
            case 'Fuzzy Logic Controller'
                modelPath = 'FLC_ACC/test 3';
        end
        evalin('base', sprintf("open_system('%s')", modelPath));
        pause(0.5); % đợi mô hình load
        evalin('base', sprintf("signalbuilder('%s')", modelPath));
    catch ME
        uialert(app.SimulinkGUI, ME.message, 'Lỗi khi mở Signal Builder');
    end
   
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
       % Cập nhật các thông số
       switch app.ModeDropDown.Value
           case 'PID Controller'
            % Khối lượng
            mass = app.KhoiluongxeEditField.Value;
            set_param('ACCSimulationByUsingPID/ACC Vehicle/Subsystem2/Vehicle Body', 'mass', num2str(mass));
                    
            % Công suất max
            app.CongsuatmaxEditField.Value = str2double(get_param('ACCSimulationByUsingPID/ACC Vehicle/Subsystem/Engine', 'P_max'));
       
            % Tốc độ vòng quay động cơ
            app.TocdodongcomaxEditField.Value = str2double(get_param('ACCSimulationByUsingPID/ACC Vehicle/Subsystem/Engine', 'w_max'));
           case 'Fuzzy Logic Controller'
                % Khối lượng
            mass = app.KhoiluongxeEditField.Value;
            set_param('FLC_ACC/ACC Car/ACC Car/Car/Subsystem2/Vehicle Body', 'mass', num2str(mass));
                 
            % Công suất max
            app.CongsuatmaxEditField.Value = str2double(get_param('FLC_ACC/ACC Car/ACC Car/Car/Engine', 'P_max'));
            % Tốc độ vòng quay động cơ
            app.TocdodongcomaxEditField.Value = str2double(get_param('FLC_ACC/ACC Car/ACC Car/Car/Engine', 'w_max'));
       end 
            
           
    % Cập nhật Logic
    switch app.ModeDropDown.Value
        case 'PID Controller'
            % casePID(app);
             % Cập nhật Tín hiệu
            switch app.TinhieudieukhienDropDown.Value
            case 'Chu trình Hyundai'
                case_Huyndai_PID(app);
            case 'Tín hiệu khác'
                other_case_PID(app);
            end 
        case 'Fuzzy Logic Controller'
            % caseFuzzy(app);
            switch app.TinhieudieukhienDropDown.Value
            case 'Chu trình Hyundai'
                case_Hyundai_Fuzzy(app);         
                case 'Tín hiệu khác'
                other_case_Fuzzy(app);
            end 
    end 

    % Cập nhật Tín hiệu
    
        try 
        sendPIDToSerial(app);      % <-- Gửi trước khi mô phỏng
       
        catch
        end

        pause(1);
             
        % Thiết lập callback: gọi hàm khi đủ 8 byte
        app.isReceiving = true; 
       while app.isReceiving == true
        configureCallback(app.serialObj, "byte", 8, @(src, event) receiveTPS12_0(app, src, event));
        receiveTPS12_0(app, app.serialObj, []);
       end 

        delete(app.serialObj);
        clear app.serialObj;
        app.serialObj = [];
        pause(0.2); 
        msgbox("Đã gửi hệ số thành công");
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
    switch app.ModeDropDown.Value
        case 'PID Controller'
           model1 = 'ACCSimulationByUsingPID';
            if ~bdIsLoaded(model1)
                load_system(model1);
            end 
            set_param(model1, 'SimulationCommand', 'start');
          
        % Actual_calc(app); 
        case 'Fuzzy Logic Controller'
            model2 = 'FLC_ACC';
            if ~bdIsLoaded(model2)
                load_system(model2);
            end 
            set_param(model2, 'SimulationCommand', 'start');
    end 
            % Khởi động Timer cập nhật GUI
            if ~isempty(app.timerObj) && isvalid(app.timerObj)
                stop(app.timerObj);
                delete(app.timerObj);
            end

            pause(0.2);
            
            try 
         app.timerObj = timer( ...
        'ExecutionMode', 'fixedRate', ...
        'Period', 0.03, ...
        'TimerFcn', @(~,~) updateEditFields(app));

        start(app.timerObj);
        disp('Đang gửi');
        catch err2
                disp (+err2.message)
            end 
 
   
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
        switch app.ModeDropDown.Value
           case 'PID Controller'
                set_param('ACCSimulationByUsingPID', 'SimulationCommand', 'stop');
           case 'Fuzzy Logic Controller'
                set_param('FLC_ACC', 'SimulationCommand', 'stop');
        end 
                       
        app.vACCEditField.Value = 0;
        app.VleadEditField.Value = 0;
        app.DMBGmmEditField.Value = 0;
        app.TPS1EditField.Value = 0;
        app.TPS2EditField.Value = 0;
        app.ThoigianEditField.Value = 0;
        app.DMBGTTeEditField.Value = 0;
        % app.SaisotuyetdoiEditField.Value = 0;  
        
         % 1. Xóa dữ liệu log
        app.TimeLog = [];
        app.VAccLog = [];
        app.VLeadLog = [];
        app.DMBGLog = [];
        app.TPS1Log = [];
        app.TPS2Log = [];
        % app.ErrorLog = []; 
        app.ActualLog = [];
        % 2. Xóa đồ thị
        cla(app.Tocdoxe);  % Clear axes (cla = clear axes)
        cla(app.Domobuomgamongmuon);
        cla(app.DMBGTTe);
        cla(app.TPS);
        % cla(app.Saisotuyetdoi); 
        
        assignin('base', 'sendData', []);
        assignin('base', 'sendTPS', []);
        cleanup(app);
        
        pause(0.5);
        SendEndingToSerial(app);
        end

        % Close request function: SimulinkGUI
        function SimulinkGUICloseRequest(app, event)
            delete(app) 
        end

        % Value changed function: CknDropDown
        function CknDropDownValueChanged(app, event)
           valueCOM = app.CknDropDown.Value;   
           app.CongCOM = valueCOM;
           assignin('base', "valueCOM", valueCOM);
        end

        % Value changed function: TinhieudieukhienDropDown
        function TinhieudieukhienDropDownValueChanged(app, event)
          
        end

        % Value changed function: VSetEditField
        function VSetEditFieldValueChanged(app, event)
            % V_Set
            v_desired = app.VSetEditField.Value;
            assignin('base', 'v_desired', v_desired);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SimulinkGUI = uifigure('Visible', 'off');
            app.SimulinkGUI.Name = 'MATLAB App';
            app.SimulinkGUI.CloseRequestFcn = createCallbackFcn(app, @SimulinkGUICloseRequest, true);
            app.Image = uiimage(app.SimulinkGUI);
            app.Image.ImageSource = fullfile(pathToMLAPP, '01_logobachkhoasang.png');
            app.Image2 = uiimage(app.SimulinkGUI);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'FTE_icon_smallest.png');
            app.Image3 = uiimage(app.SimulinkGUI);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'FAST (OPT 2) 26.06.svg');
            app.TRNGIHCBCHKHOAHQGTPHCMKhoaKThutGiaoThngLabel = uilabel(app.SimulinkGUI);
            app.TRNGIHCBCHKHOAHQGTPHCMKhoaKThutGiaoThngLabel.Text = {'TRƯỜNG ĐẠI HỌC BÁCH KHOA - ĐHQG TP. HCM'; 'Khoa: Kỹ Thuật Giao Thông'; '------------------------------------'};
            app.GIAODINIUKHINBMGALabel = uilabel(app.SimulinkGUI);
            app.GIAODINIUKHINBMGALabel.Text = 'GIAO DIỆN ĐIỀU KHIỂN BƯỚM GA';
            app.Input = uitabgroup(app.SimulinkGUI);
            app.HsbmgaTab = uitab(app.Input);
            app.HsbmgaTab.Title = 'Hệ số bướm ga';
            app.HsKpEditFieldLabel = uilabel(app.HsbmgaTab);
            app.HsKpEditFieldLabel.Text = 'Hệ số Kp';
            app.HsKpEditField = uieditfield(app.HsbmgaTab, 'numeric');
            app.HsKiEditFieldLabel = uilabel(app.HsbmgaTab);
            app.HsKiEditFieldLabel.Text = 'Hệ số Ki';
            app.HsKiEditField = uieditfield(app.HsbmgaTab, 'numeric');
            app.HsblcEditFieldLabel = uilabel(app.HsbmgaTab);
            app.HsblcEditFieldLabel.Text = 'Hệ số bộ lọc';
            app.HesobolocEditField = uieditfield(app.HsbmgaTab, 'numeric');
            app.ThongsoxeTab = uitab(app.Input);
            app.ThongsoxeTab.Title = 'Thông số xe';
            app.KhilngxekgEditFieldLabel = uilabel(app.ThongsoxeTab);
            app.KhilngxekgEditFieldLabel.Text = 'Khối lượng xe (kg)';
            app.KhoiluongxeEditField = uieditfield(app.ThongsoxeTab, 'numeric');
            app.KhoiluongxeEditField.ValueChangedFcn = createCallbackFcn(app, @KhoiluongxeEditFieldValueChanged, true);
            app.CngsutmaxLabel = uilabel(app.ThongsoxeTab);
            app.CngsutmaxLabel.Text = 'Công suất max (kW)';
            app.CongsuatmaxEditField = uieditfield(app.ThongsoxeTab, 'numeric');
            app.CongsuatmaxEditField.ValueChangedFcn = createCallbackFcn(app, @CongsuatmaxEditFieldValueChanged, true);
            app.TcngcmaxrpmEditFieldLabel = uilabel(app.ThongsoxeTab);
            app.TcngcmaxrpmEditFieldLabel.Text = 'Tốc độ động cơ max (rpm)';
            app.TocdodongcomaxEditField = uieditfield(app.ThongsoxeTab, 'numeric');
            app.TocdodongcomaxEditField.ValueChangedFcn = createCallbackFcn(app, @TocdodongcomaxEditFieldValueChanged, true);
            app.ChondongcoButton = uibutton(app.ThongsoxeTab, 'push');
            app.ChondongcoButton.ButtonPushedFcn = createCallbackFcn(app, @ChondongcoButtonPushed, true);
            app.ChondongcoButton.Text = 'Chọn động cơ';
            app.ThsodauvaoTab = uitab(app.Input);
            app.ThsodauvaoTab.Title = 'Thông số đầu vào';
            app.TcgiihnmsEditFieldLabel = uilabel(app.ThsodauvaoTab);
            app.TcgiihnmsEditFieldLabel.Text = 'Tốc độ giới hạn (m/s) ';
            app.VSetEditField = uieditfield(app.ThsodauvaoTab, 'numeric');
            app.VSetEditField.ValueChangedFcn = createCallbackFcn(app, @VSetEditFieldValueChanged, true);
            app.VtrtngimEditFieldLabel = uilabel(app.ThsodauvaoTab);
            app.VtrtngimEditFieldLabel.Text = 'Vị trí tương đối (m)';
            app.VTTDEditField = uieditfield(app.ThsodauvaoTab, 'numeric');
            app.VTTDEditField.ValueChangedFcn = createCallbackFcn(app, @VTTDEditFieldValueChanged, true);
            app.KhongcchtithiumLabel = uilabel(app.ThsodauvaoTab);
            app.KhongcchtithiumLabel.Text = 'Khoảng cách tối thiểu (m)';
            app.KhoangcachantoanEditField = uieditfield(app.ThsodauvaoTab, 'numeric');
            app.KhoangcachantoanEditField.ValueChangedFcn = createCallbackFcn(app, @KhoangcachantoanEditFieldValueChanged, true);
            app.TychniukhinTab = uitab(app.Input);
            app.TychniukhinTab.Title = 'Tùy chọn điều khiển';
            app.ModeDropDownLabel = uilabel(app.TychniukhinTab);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.TychniukhinTab);
            app.ModeDropDown.Items = {'PID Controller', 'Fuzzy Logic Controller'};
            app.ModeDropDown.Value = 'PID Controller';
            app.TnhiuiukhinDropDownLabel = uilabel(app.TychniukhinTab);
            app.TnhiuiukhinDropDownLabel.Text = 'Tín hiệu điều khiển';
            app.TinhieudieukhienDropDown = uidropdown(app.TychniukhinTab);
            app.TinhieudieukhienDropDown.Items = {'Chu trình Hyundai', 'Tín hiệu khác'};
            app.TinhieudieukhienDropDown.ValueChangedFcn = createCallbackFcn(app, @TinhieudieukhienDropDownValueChanged, true);
            app.TinhieudieukhienDropDown.Value = 'Chu trình Hyundai';
            app.ChncngktniDropDownLabel = uilabel(app.TychniukhinTab);
            app.ChncngktniDropDownLabel.Text = 'Chọn cổng kết nối ';
            app.CknDropDown = uidropdown(app.TychniukhinTab);
            app.CknDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10'};
            app.CknDropDown.ValueChangedFcn = createCallbackFcn(app, @CknDropDownValueChanged, true);
            app.CknDropDown.Value = 'COM1';
            app.TaoSignalButton = uibutton(app.TychniukhinTab, 'push');
            app.TaoSignalButton.ButtonPushedFcn = createCallbackFcn(app, @TaoSignalButtonPushed, true);
            app.TaoSignalButton.Text = 'Tạo Signal';
            app.thDropDownLabel = uilabel(app.SimulinkGUI);
            app.thDropDownLabel.Text = 'Đồ thị';
            app.ChondothiDropDown = uidropdown(app.SimulinkGUI);
            app.ChondothiDropDown.Items = {'Tốc độ xe thực tế', 'Độ mở bướm ga mong muốn', 'Độ mở bướm ga thực tế', 'TPS'};
            app.ChondothiDropDown.ValueChangedFcn = createCallbackFcn(app, @ChondothiDropDownValueChanged, true);
            app.ChondothiDropDown.Value = 'Tốc độ xe thực tế';
            app.ThigianmphngsLabel = uilabel(app.SimulinkGUI);
            app.ThigianmphngsLabel.Text = 'Thời gian mô phỏng (s)';
            app.ThoigianEditField = uieditfield(app.SimulinkGUI, 'numeric');
            app.Dothi = uitabgroup(app.SimulinkGUI);
            app.TcxethctTab = uitab(app.Dothi);
            app.TcxethctTab.Title = 'Tốc độ xe thực tế';
            app.Tocdoxe = uiaxes(app.TcxethctTab);
            title(app.Tocdoxe, 'Đồ thị so sánh tốc độ xe chạy ACC và xe phía trước')
            xlabel(app.Tocdoxe, 'Thời gian')
            ylabel(app.Tocdoxe, 'Tốc độ xe (m/s)')
            app.Tocdoxe.XLim = [0 600];
            app.Tocdoxe.YLim = [0 30];
            app.Tocdoxe.ZLim = [0 30];
            app.mbmgamongmunTab = uitab(app.Dothi);
            app.mbmgamongmunTab.Title = 'Độ mở bướm ga mong muốn';
            app.Domobuomgamongmuon = uiaxes(app.mbmgamongmunTab);
            title(app.Domobuomgamongmuon, 'Đồ thị độ mở bướm ga mong muốn')
            xlabel(app.Domobuomgamongmuon, 'Thời gian (s)')
            ylabel(app.Domobuomgamongmuon, 'Độ mở bướm ga (%)')
            zlabel(app.Domobuomgamongmuon, 'Z')
            app.Domobuomgamongmuon.XLim = [0 600];
            app.Domobuomgamongmuon.YLim = [0 80];
            app.mbmgathctTab = uitab(app.Dothi);
            app.mbmgathctTab.Title = 'Độ mở bướm ga thực tế';
            app.DMBGTTe = uiaxes(app.mbmgathctTab);
            title(app.DMBGTTe, 'Độ mở bướm ga thực tế')
            xlabel(app.DMBGTTe, 'Thời gian (s)')
            ylabel(app.DMBGTTe, 'Độ mở bướm ga thực tế (%)')
            zlabel(app.DMBGTTe, 'Z')
            app.DMBGTTe.XLim = [0 600];
            app.DMBGTTe.YLim = [0 80];
            app.TPSTab = uitab(app.Dothi);
            app.TPSTab.Title = 'TPS';
            app.TPS = uiaxes(app.TPSTab);
            title(app.TPS, 'Đồ thị TPS1 và TPS2')
            xlabel(app.TPS, 'Thời gian (s)')
            ylabel(app.TPS, 'TPS')
            app.TPS.XLim = [0 600];
            app.TPS.YLim = [0 5000];
            app.TPS.ZLim = [0 5000];
            app.KqmophongPanel = uipanel(app.SimulinkGUI);
            app.KqmophongPanel.Title = 'Kết quả mô phỏng';
            app.TPS1EditFieldLabel = uilabel(app.KqmophongPanel);
            app.TPS1EditFieldLabel.Text = 'TPS1';
            app.TPS1EditField = uieditfield(app.KqmophongPanel, 'numeric');
            app.TPS2EditFieldLabel = uilabel(app.KqmophongPanel);
            app.TPS2EditFieldLabel.Text = 'TPS2';
            app.TPS2EditField = uieditfield(app.KqmophongPanel, 'numeric');
            app.TcxephatrcmsEditFieldLabel = uilabel(app.KqmophongPanel);
            app.TcxephatrcmsEditFieldLabel.Text = 'Tốc độ xe phía trước (m/s)';
            app.VleadEditField = uieditfield(app.KqmophongPanel, 'numeric');
            app.TcxeACCmsEditFieldLabel = uilabel(app.KqmophongPanel);
            app.TcxeACCmsEditFieldLabel.Text = 'Tốc độ xe ACC (m/s)';
            app.vACCEditField = uieditfield(app.KqmophongPanel, 'numeric');
            app.mbmgaLabel = uilabel(app.KqmophongPanel);
            app.mbmgaLabel.Text = 'Độ mở bướm ga mong muốn (%)';
            app.DMBGmmEditField = uieditfield(app.KqmophongPanel, 'numeric');
            app.mbmgathctEditFieldLabel = uilabel(app.KqmophongPanel);
            app.mbmgathctEditFieldLabel.Text = 'Độ mở bướm ga thực tế (%)';
            app.DMBGTTeEditField = uieditfield(app.KqmophongPanel, 'numeric');
            app.UpdateButton = uibutton(app.SimulinkGUI, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.StartButton = uibutton(app.SimulinkGUI, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.SimulinkGUI, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.SimulinkGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OfficialApp

            % Associate the Simulink Model
            app.Simulation = simulation('ACCSimulationByUsingPID');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimulinkGUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimulinkGUI)
        end
    end
end