classdef subApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Bientro                  matlab.ui.Figure
        ResetButton              matlab.ui.control.Button
        ThoigianEditField        matlab.ui.control.NumericEditField
        ThigianmphngsLabel       matlab.ui.control.Label
        ThngsuvoPanel            matlab.ui.container.Panel
        CknDropDown              matlab.ui.control.DropDown
        ChncngktniDropDownLabel  matlab.ui.control.Label
        HesobolocEditField       matlab.ui.control.NumericEditField
        HsblcEditFieldLabel      matlab.ui.control.Label
        HsKiEditField            matlab.ui.control.NumericEditField
        HsKiEditFieldLabel       matlab.ui.control.Label
        HsKpEditField            matlab.ui.control.NumericEditField
        HsKpEditFieldLabel       matlab.ui.control.Label
        ChondothiDropDown        matlab.ui.control.DropDown
        thDropDownLabel          matlab.ui.control.Label
        UpdateandStartButton     matlab.ui.control.Button
        KqmophongPanel           matlab.ui.container.Panel
        DMBGTTeEditField         matlab.ui.control.NumericEditField
        mbmgathctEditFieldLabel  matlab.ui.control.Label
        TPS2EditField            matlab.ui.control.NumericEditField
        TPS2EditFieldLabel       matlab.ui.control.Label
        TPS1EditField            matlab.ui.control.NumericEditField
        TPS1EditFieldLabel       matlab.ui.control.Label
        Dothi                    matlab.ui.container.TabGroup
        mbmgathctTab             matlab.ui.container.Tab
        DMBGTTe                  matlab.ui.control.UIAxes
        TPSTab                   matlab.ui.container.Tab
        TPS                      matlab.ui.control.UIAxes
        GIAODINIUKHINBMGALabel   matlab.ui.control.Label
        TRNGIHCBCHKHOAHQGTPHCMKhoaKThutGiaoThngLabel  matlab.ui.control.Label
        Image3                   matlab.ui.control.Image
        Image2                   matlab.ui.control.Image
        Image                    matlab.ui.control.Image
    end

    
    properties (Access = private)
        timerObj
        TimeLog = [];     % Lưu thời gian
        TPS1Log = [];        % Lưu giá trị TPS1
        TPS2Log = [];        % Lưu giá trị TPS2
        ActualLog = [];
        TPSmin double;
        TPSmax double; 
        CongCOM
        TPSminList = [];
        TPSmaxList = [];
        isReceiving;
    end
    
    properties (Access = public)
        OfficialApp % Description
    end
    
 
    methods (Access = public)
        function sendPIDToSerial(app)
    try
        % Mở cổng COM
        app.serialObj = serialport(app.CongCOM, 115200); % Thay COM3 bằng đúng cổng bạn dùng
        pause(0.2);  % Cho cổng ổn định

        % Đóng gói và gửi Kp (ID = 1)
        Kp = app.HsKpEditField.Value;
        assignin('base', "Kp", Kp);
        id1 = uint8(1);
        Kp1 = single(Kp);
        dataKp = [id1, typecast(Kp1, 'uint8')];
        write(app.serialObj, dataKp, "uint8");

        pause(0.1);  % delay nhẹ

        % Đóng gói và gửi Ki (ID = 2)
        Ki = app.HsKiEditField.Value;
        assignin('base', "Ki", Ki);
        id2 = uint8(2);
        Ki1 = single(Ki);
        dataKi = [id2, typecast(Ki1, 'uint8')];
        write(app.serialObj, dataKi, "uint8");

        % Đóng gói và gửi hệ số bộ lọc (ID = 4)
        alpha = app.HesobolocEditField.Value;
        id4 = uint8(4);
        alpha1 = single(alpha);
        dataalpha = [id4, typecast(alpha1, 'uint8')];
        write(app.serialObj, dataalpha, "uint8");

    catch err
        disp("Lỗi gửi dữ liệu: " + err.message);
    end
end

%------------------------------------------------------------------------------------------------------------------------------------------------- 
function receiveTPSminmax_0(app, src, ~)
     try
        if ~app.isReceiving
            configureCallback(src, "off");
            return;
        end

        if src.NumBytesAvailable >= 8
            rawData = read(src, 8, "uint8");
            tpsmin_bytes = rawData(1:4);
            tpsmax_bytes = rawData(5:8);

            % Chuyển sang float và double
            TPSmin_0 = double(typecast(uint8(tpsmin_bytes), 'single'));
            TPSmax_0 = double(typecast(uint8(tpsmax_bytes), 'single'));

            % Hiển thị lên giao diện
            % app.TPS1EditField.Value = TPSmin_0;
            % app.TPS2EditField.Value = TPSmax_0;

            % Cập nhật vào biến app
            app.TPSmin = TPSmin_0;
            app.TPSmax = TPSmax_0;

            % Kiểm tra điều kiện dừng
            if TPSmin_0 < 0 || TPSmax_0 < 0
                app.isReceiving = false;
                configureCallback(src, "off");

                % In ra giá trị cuối cùng
                disp('DỪNG NHẬN DỮ LIỆU!');
                disp(['TPSmin: ', num2str(app.TPSmin)]);
                disp(['TPSmax: ', num2str(app.TPSmax)]);
            end
        end
    catch err3
        disp(['Lỗi: ', err3.message])
    end
end
%--------------------------------------------------------------------------------------------------------------------------------
        function tinhtoan_bientro(app, src, ~)
        try
            Thoigian(app); 
            if ~app.isReceiving
            configureCallback(src, "off");
            return;
            end 

           if src.NumBytesAvailable >= 8
            rawData = read(src, 8, "uint8");
            tps1_bytes = rawData(1:4);
            tps2_bytes = rawData(5:8);

            % Chuyển sang float và double
            TPS1_0 = double(typecast(uint8(tps1_bytes), 'single'));
            TPS2_0 = double(typecast(uint8(tps2_bytes), 'single'));

            % Hiển thị lên giao diện
            app.TPS1EditField.Value = TPS1_0;
            app.TPS2EditField.Value = TPS2_0;

            % Cập nhật vào biến app
            app.TPSmin = TPSmin_0;
            app.TPSmax = TPSmax_0;

            app.TPS1Log(end+1) = TPS1_0;
            app.TPS2Log(end+1) = TPS2_0; 
            

            try
            plot(app.TPS, app.TimeLog, app.TPS1Log, 'b',...
                                       app.TimeLog, app.TPS2Log, 'r');
            legend(app.TPS, {'TPS1', 'TPS2'}, 'Location', 'best');

            catch er1
                disp("Lỗi vẽ đồ thị" + er1.message)
            end 
           end
         % Tính actual trên toàn bộ vector
TPSmax1 = app.TPSmax;
TPSmin1 = app.TPSmin;

actual = ((app.TPS1Log - app.TPS2Log) - TPSmin1) * 100 / (TPSmax1 - TPSmin1);

% Clamp từng phần tử actual trong khoảng [0, 100]
actual(actual < 0) = 0;
actual(actual > 100) = 100;

% Lấy giá trị actual mới nhất (phần tử cuối cùng) để hiển thị trên EditField
latestActual = actual(end);

app.DMBGTTeEditField.Value = latestActual;

% Lưu lại actual vào log để vẽ đồ thị
app.ActualLog(end+1) = latestActual;

% Vẽ đồ thị actual
plot(app.DMBGTTe, app.TimeLog, app.ActualLog);
        catch 
        end 
        end
%----------------------------------------------------------------------------------------------------------------------------        
    end 
    
    methods (Access = private)
        function Thoigian(app)
     app.secondsElapsed = app.secondsElapsed + 1;
     app.ThoigianEditField.Value = app.secondsElapsed;  % cập nhật ô số
     app.TimeLog(end+1) = app.secondsElapsed;
        end
    end 
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UpdateandStartButton
        function UpdateandStartButtonPushed(app, event)
          sendPIDToSerial(app);
          pause(1)
          app.isReceiving = true; 
       while app.isReceiving == true
        configureCallback(app.serialObj, "byte", 8, @(src, event) receiveTPSminmax_0(app, src, event));
        receiveTPSminmax_0(app, app.serialObj, []);
       end 
       pause(2);
       msgbox("Đã gửi và ổn định hệ thống");


    app.secondsElapsed = 0;  % reset về 0
    app.ThoigianEditField.Value = 0;  % cũng reset hiển thị

    app.isReceiving = true;
    % Dừng timer cũ nếu còn
    if ~isempty(app.timerObj) && isvalid(app.timerObj)
        stop(app.timerObj);
        delete(app.timerObj);
    end       
       app.timerObj = timer( ...
        'ExecutionMode', 'fixedRate', ...
        'Period', 0.5, ...
        'TimerFcn', @(~,~) tinhtoan_bientro(app));

        start(app.timerObj);
        end

        % Value changed function: CknDropDown
        function CknDropDownValueChanged(app, event)
           valueCOM = app.CknDropDown.Value;   
           app.CongCOM = valueCOM;

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
        app.isReceiving = false; 
        configureCallback(src, "off");
        
        app.secondsElapsed = 0;  % reset về 0

        app.TPS1EditField.Value = 0;
        app.TPS2EditField.Value = 0;
        app.ThoigianEditField.Value = 0;
        app.DMBGTTeEditField.Value = 0;
   
        
         % 1. Xóa dữ liệu log
        app.TimeLog = [];
        app.DMBGLog = [];
        app.TPS1Log = [];
        app.TPS2Log = [];
        app.ActualLog = [];
        % 2. Xóa đồ thị
       
     
        cla(app.DMBGTTe);
        cla(app.TPS);


       if ~isempty(app.timerObj) && isvalid(app.timerObj)
            stop(app.timerObj);
            delete(app.timerObj);
            app.timerObj = [];
        end
        
        pause(0.5);
        
        end

        % Value changed function: ChondothiDropDown
        function ChondothiDropDownValueChanged(app, event)
            dt = app.ChondothiDropDown.Value;
    switch dt
        
        case 'Độ mở bướm ga thực tế'
            app.Dothi.SelectedTab = app.mbmgathctTab; 
        case 'TPS'
            app.Dothi.SelectedTab = app.TPSTab;
       
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Bientro = uifigure('Visible', 'off');
            app.Bientro.Name = 'MATLAB App';
            app.Image = uiimage(app.Bientro);
            app.Image.ImageSource = fullfile(pathToMLAPP, '01_logobachkhoasang.png');
            app.Image2 = uiimage(app.Bientro);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'FTE_icon_smallest.png');
            app.Image3 = uiimage(app.Bientro);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'FAST (OPT 2) 26.06.svg');
            app.TRNGIHCBCHKHOAHQGTPHCMKhoaKThutGiaoThngLabel = uilabel(app.Bientro);
            app.TRNGIHCBCHKHOAHQGTPHCMKhoaKThutGiaoThngLabel.Text = {'TRƯỜNG ĐẠI HỌC BÁCH KHOA - ĐHQG TP. HCM'; 'Khoa: Kỹ Thuật Giao Thông'; '------------------------------------'};
            app.GIAODINIUKHINBMGALabel = uilabel(app.Bientro);
            app.GIAODINIUKHINBMGALabel.Text = 'GIAO DIỆN ĐIỀU KHIỂN BƯỚM GA';
            app.Dothi = uitabgroup(app.Bientro);
            app.mbmgathctTab = uitab(app.Dothi);
            app.mbmgathctTab.Title = 'Độ mở bướm ga thực tế';
            app.DMBGTTe = uiaxes(app.mbmgathctTab);
            title(app.DMBGTTe, 'Độ mở bướm ga thực tế')
            xlabel(app.DMBGTTe, 'Thời gian (s)')
            ylabel(app.DMBGTTe, 'Độ mở bướm ga thực tế (%)')
            zlabel(app.DMBGTTe, 'Z')
            app.DMBGTTe.XLim = [0 600];
            app.DMBGTTe.YLim = [0 80];
            app.TPSTab = uitab(app.Dothi);
            app.TPSTab.Title = 'TPS';
            app.TPS = uiaxes(app.TPSTab);
            title(app.TPS, 'Đồ thị TPS1 và TPS2')
            xlabel(app.TPS, 'Thời gian (s)')
            ylabel(app.TPS, 'TPS')
            app.TPS.XLim = [0 600];
            app.TPS.YLim = [0 5000];
            app.TPS.ZLim = [0 5000];
            app.KqmophongPanel = uipanel(app.Bientro);
            app.KqmophongPanel.Title = 'Kết quả mô phỏng';
            app.TPS1EditFieldLabel = uilabel(app.KqmophongPanel);
            app.TPS1EditFieldLabel.Text = 'TPS1';
            app.TPS1EditField = uieditfield(app.KqmophongPanel, 'numeric');
            app.TPS2EditFieldLabel = uilabel(app.KqmophongPanel);
            app.TPS2EditFieldLabel.Text = 'TPS2';
            app.TPS2EditField = uieditfield(app.KqmophongPanel, 'numeric');
            app.mbmgathctEditFieldLabel = uilabel(app.KqmophongPanel);
            app.mbmgathctEditFieldLabel.Text = 'Độ mở bướm ga thực tế (%)';
            app.DMBGTTeEditField = uieditfield(app.KqmophongPanel, 'numeric');
            app.UpdateandStartButton = uibutton(app.Bientro, 'push');
            app.UpdateandStartButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateandStartButtonPushed, true);
            app.UpdateandStartButton.Text = 'Update and Start';
            app.thDropDownLabel = uilabel(app.Bientro);
            app.thDropDownLabel.Text = 'Đồ thị';
            app.ChondothiDropDown = uidropdown(app.Bientro);
            app.ChondothiDropDown.Items = {'Độ mở bướm ga thực tế', 'TPS'};
            app.ChondothiDropDown.ValueChangedFcn = createCallbackFcn(app, @ChondothiDropDownValueChanged, true);
            app.ChondothiDropDown.Value = 'Độ mở bướm ga thực tế';
            app.ThngsuvoPanel = uipanel(app.Bientro);
            app.ThngsuvoPanel.Title = 'Thông số đầu vào';
            app.HsKpEditFieldLabel = uilabel(app.ThngsuvoPanel);
            app.HsKpEditFieldLabel.Text = 'Hệ số Kp';
            app.HsKpEditField = uieditfield(app.ThngsuvoPanel, 'numeric');
            app.HsKiEditFieldLabel = uilabel(app.ThngsuvoPanel);
            app.HsKiEditFieldLabel.Text = 'Hệ số Ki';
            app.HsKiEditField = uieditfield(app.ThngsuvoPanel, 'numeric');
            app.HsblcEditFieldLabel = uilabel(app.ThngsuvoPanel);
            app.HsblcEditFieldLabel.Text = 'Hệ số bộ lọc';
            app.HesobolocEditField = uieditfield(app.ThngsuvoPanel, 'numeric');
            app.ChncngktniDropDownLabel = uilabel(app.ThngsuvoPanel);
            app.ChncngktniDropDownLabel.Text = 'Chọn cổng kết nối ';
            app.CknDropDown = uidropdown(app.ThngsuvoPanel);
            app.CknDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10'};
            app.CknDropDown.ValueChangedFcn = createCallbackFcn(app, @CknDropDownValueChanged, true);
            app.CknDropDown.Value = 'COM1';
            app.ThigianmphngsLabel = uilabel(app.Bientro);
            app.ThigianmphngsLabel.Text = 'Thời gian (s)';
            app.ThoigianEditField = uieditfield(app.Bientro, 'numeric');
            app.ResetButton = uibutton(app.Bientro, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.Bientro.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = subApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Bientro)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Bientro)
        end
    end
end